classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        StartButton             matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        Initial_picture         matlab.ui.control.UIAxes
        InputdeviceButtonGroup  matlab.ui.container.ButtonGroup
        TestImagesButton        matlab.ui.control.RadioButton
        IPCamButton             matlab.ui.control.RadioButton
        IPEditFieldLabel        matlab.ui.control.Label
        IPEditField             matlab.ui.control.EditField
        BombDetectedLampLabel   matlab.ui.control.Label
        BombDetectedLamp        matlab.ui.control.Lamp
        BombIDEditFieldLabel    matlab.ui.control.Label
        BombIDEditField         matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            im_initial = imread('robotica.png');
            imshow(im_initial, 'parent', app.Initial_picture); 
            app.StopButton.Visible = 'off';
            app.TestImagesButton.Value = 1;
            app.BombDetectedLamp.Enable = 'off';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %----&&----&&----&&----%
            %window setup
            app.Initial_picture.Position = [0 0 0 0];
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.StopButton.Visible = 'on';
            app.StartButton.Visible = 'off';
            
            
            selectedButton = app.InputdeviceButtonGroup.SelectedObject;
            if selectedButton == app.TestImagesButton
                app.IPEditField.Enable = 'off';
                %read image
%                 img = imread('danger.jpg');         % yellow signal picture
%                 img = imread('numbers.jpg');       % text picture  
            else 
                %ipcam option - get ip (reading app.IPEditField)
%                 ip = app.IPEditField.Value;                
%                 url = ('http://'+ip+'/shot.jpg');
%                 img = imread(url);
            end
            
                        % connection by USB
            myev3 = legoev3('USB');
            batteryLevel = myev3.BatteryLevel();
            
            % sensors
            mysonicsensor = sonicSensor(myev3);
            mygyrosensor  = gyroSensor(myev3);
            mycolorsensor = colorSensor(myev3);
            mytouchsensor = touchSensor(myev3);
            
            % motors
            leftMotor  = motor(myev3,'B');
            rightMotor = motor(myev3,'C');
            
            if batteryLevel >= 10
                beep(myev3,1);
                searchingCode();
            else
                fprintf('Warning! Low battery!')
                beep(myev3,2)
end
            
       
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Visible ='off';
            app.StartButton.Visible ='on';
            app.BombDetectedLamp.Enable=0;
            app.BombIDEditField.Value='';
            app.Initial_picture.Position = [1 116 599 245]; %Default settings
        end

        % Selection changed function: InputdeviceButtonGroup
        function InputdeviceButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputdeviceButtonGroup.SelectedObject;
            if selectedButton == app.TestImagesButton
                app.IPEditField.Enable = 'off';   
            else 
                app.IPEditField.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Processed Image')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.UIAxes2.Visible = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Initial_picture = uiaxes(app.UIFigure);
            app.Initial_picture.XTickLabel = '';
            app.Initial_picture.YTickLabel = '';
            app.Initial_picture.Visible = 'off';
            app.InputdeviceButtonGroup = uibuttongroup(app.UIFigure);
            app.InputdeviceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputdeviceButtonGroupSelectionChanged, true);
            app.InputdeviceButtonGroup.Title = 'Input device';
            app.TestImagesButton = uiradiobutton(app.InputdeviceButtonGroup);
            app.TestImagesButton.Text = 'TestImages';
            app.TestImagesButton.Value = true;
            app.IPCamButton = uiradiobutton(app.InputdeviceButtonGroup);
            app.IPCamButton.Text = 'IPCam';
            app.IPEditFieldLabel = uilabel(app.InputdeviceButtonGroup);
            app.IPEditFieldLabel.Text = 'IP:';
            app.IPEditField = uieditfield(app.InputdeviceButtonGroup, 'text');
            app.BombDetectedLampLabel = uilabel(app.UIFigure);
            app.BombDetectedLampLabel.Text = 'Bomb Detected';
            app.BombDetectedLamp = uilamp(app.UIFigure);
            app.BombIDEditFieldLabel = uilabel(app.UIFigure);
            app.BombIDEditFieldLabel.Text = 'Bomb ID';
            app.BombIDEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end